classdef FlexibilityUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        CaseNameEditFieldLabel          matlab.ui.control.Label
        CaseNameEditField               matlab.ui.control.EditField
        YearSpinnerLabel                matlab.ui.control.Label
        YearSpinner                     matlab.ui.control.Spinner
        RevenueCheckBox                 matlab.ui.control.CheckBox
        BasicInformationLabel           matlab.ui.control.Label
        ModuleSelectionLabel            matlab.ui.control.Label
        RiskCheckBox                    matlab.ui.control.CheckBox
        CostCheckBox                    matlab.ui.control.CheckBox
        TechnologyConstraintsCheckBox   matlab.ui.control.CheckBox
        MarketConstraintsCheckBox       matlab.ui.control.CheckBox
        OptionsButton                   matlab.ui.control.Button
        MarketSimulationCheckBox        matlab.ui.control.CheckBox
        ModeSwitch                      matlab.ui.control.Switch
        MarketTab                       matlab.ui.container.Tab
        RegimeDropDownLabel             matlab.ui.control.Label
        RegimeDropDown                  matlab.ui.control.DropDown
        SelectButton                    matlab.ui.control.Button
        DayaheadCheckBox                matlab.ui.control.CheckBox
        RealtimeCheckBox                matlab.ui.control.CheckBox
        EnergyMarketsLabel              matlab.ui.control.Label
        IntradayCheckBox                matlab.ui.control.CheckBox
        BalancingCheckBox               matlab.ui.control.CheckBox
        ReserveMarketsLabel             matlab.ui.control.Label
        PrimaryControlCheckBox          matlab.ui.control.CheckBox
        SecondaryControlCheckBox        matlab.ui.control.CheckBox
        SecondaryControlCheckBox_2      matlab.ui.control.CheckBox
        RegulationRegDCheckBox          matlab.ui.control.CheckBox
        RegulationRegACheckBox          matlab.ui.control.CheckBox
        TechnologyTab                   matlab.ui.container.Tab
        SelectButton_2                  matlab.ui.control.Button
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        StorageEfficiencyEditFieldLabel  matlab.ui.control.Label
        StorageEfficiencyEditField      matlab.ui.control.NumericEditField
        DischargeEfficiencyEditFieldLabel  matlab.ui.control.Label
        DischargeEfficiencyEditField    matlab.ui.control.NumericEditField
        ChargeEfficiencyEditFieldLabel  matlab.ui.control.Label
        ChargeEfficiencyEditField       matlab.ui.control.NumericEditField
        BatterySizePerVehicleKWEditFieldLabel  matlab.ui.control.Label
        BatterySizePerVehicleKWEditField  matlab.ui.control.NumericEditField
        EnergytoPowerRatioMWhMWEditFieldLabel  matlab.ui.control.Label
        EnergytoPowerRatioMWhMWEditField  matlab.ui.control.NumericEditField
        InvestmentEnergyCostKWhEditFieldLabel  matlab.ui.control.Label
        InvestmentEnergyCostKWhEditField  matlab.ui.control.NumericEditField
        InvestmentPowerCostKWEditFieldLabel  matlab.ui.control.Label
        InvestmentPowerCostKWEditField  matlab.ui.control.NumericEditField
        ReplacementEnergyCostKWhEditFieldLabel  matlab.ui.control.Label
        ReplacementEnergyCostKWhEditField  matlab.ui.control.NumericEditField
        ReplacementPowerCostKWEditFieldLabel  matlab.ui.control.Label
        ReplacementPowerCostKWEditField  matlab.ui.control.NumericEditField
        NominateLifeTimeyearEditFieldLabel  matlab.ui.control.Label
        NominateLifeTimeyearEditField   matlab.ui.control.NumericEditField
        OperationalLifeTimecyclesEditFieldLabel  matlab.ui.control.Label
        OperationalLifeTimecyclesEditField  matlab.ui.control.NumericEditField
        DiscountRateLabel               matlab.ui.control.Label
        DiscountRateEditField           matlab.ui.control.NumericEditField
        InvestmentTechnologyCostEditFieldLabel  matlab.ui.control.Label
        InvestmentTechnologyCostEditField  matlab.ui.control.NumericEditField
        AdvancedTab                     matlab.ui.container.Tab
        InitialSoCs_0EditFieldLabel     matlab.ui.control.Label
        InitialSoCs_0EditField          matlab.ui.control.NumericEditField
        TimeStephEditFieldLabel         matlab.ui.control.Label
        TimeStephEditField              matlab.ui.control.NumericEditField
        ScenarioperMPEditFieldLabel     matlab.ui.control.Label
        ScenarioperMPEditField          matlab.ui.control.NumericEditField
        EnableAdvancedOptionsSwitchLabel  matlab.ui.control.Label
        EnableAdvancedOptionsSwitch     matlab.ui.control.Switch
        ScenarioperMPEditField_2        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_3        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_4        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_5        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_6        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_7        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_8        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_9        matlab.ui.control.NumericEditField
        ScenarioperMPEditField_10       matlab.ui.control.NumericEditField
        ScenarioperMPEditField_11       matlab.ui.control.NumericEditField
        OptimizingWindowdEditFieldLabel  matlab.ui.control.Label
        OptimizingWindowdEditField      matlab.ui.control.NumericEditField
        CreateCaseButton                matlab.ui.control.Button
        RunCaseStudyButton              matlab.ui.control.Button
        ShowResultButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if strcmp(app.ModeSwitch.Value, 'Retrospective')
                if strcmp(app.RegimeDropDown.Value,'PJM')
                     app.DayaheadCheckBox.Enable = 'on';
                     app.RealtimeCheckBox.Enable = 'on';
                     app.RegulationRegDCheckBox.Enable = 'on';
                     app.RegulationRegACheckBox.Enable = 'on';
                     app.IntradayCheckBox.Enable = 'off';
                     app.BalancingCheckBox.Enable = 'off';
                     app.PrimaryControlCheckBox.Enable = 'off';
                     app.SecondaryControlCheckBox.Enable = 'off';
                     app.SecondaryControlCheckBox_2.Enable = 'off';
                     app.TimeStephEditField.Value = 1;
                elseif strcmp(app.RegimeDropDown.Value, 'Germany')
                     app.DayaheadCheckBox.Enable = 'on';
                     app.RealtimeCheckBox.Enable = 'off';
                     app.RegulationRegDCheckBox.Enable = 'off';
                     app.RegulationRegACheckBox.Enable = 'off';
                     app.IntradayCheckBox.Enable = 'on';
                     app.BalancingCheckBox.Enable = 'on';
                     app.PrimaryControlCheckBox.Enable = 'on';
                     app.SecondaryControlCheckBox.Enable = 'on';
                     app.SecondaryControlCheckBox_2.Enable = 'on';
                     app.TimeStephEditField.Value = 1;
                else
                     app.DayaheadCheckBox.Enable = 'off';
                     app.RealtimeCheckBox.Enable = 'on';
                     app.RegulationRegDCheckBox.Enable = 'off';
                     app.RegulationRegACheckBox.Enable = 'off';
                     app.IntradayCheckBox.Enable = 'off';
                     app.BalancingCheckBox.Enable = 'off';
                     app.PrimaryControlCheckBox.Enable = 'off';
                     app.SecondaryControlCheckBox.Enable = 'off';
                     app.SecondaryControlCheckBox_2.Enable = 'off';
                     app.TimeStephEditField.Value = 0.5;
                end
            else
                app.DayaheadCheckBox.Enable = 'on';
                app.RealtimeCheckBox.Enable = 'off';
                app.RegulationRegDCheckBox.Enable = 'off';
                app.RegulationRegACheckBox.Enable = 'off';
                app.IntradayCheckBox.Enable = 'off';
                app.BalancingCheckBox.Enable = 'off';
                app.PrimaryControlCheckBox.Enable = 'off';
                app.SecondaryControlCheckBox.Enable = 'off';
                app.SecondaryControlCheckBox_2.Enable = 'off';
                if strcmp(app.RegimeDropDown.Value, 'AEMO-NSW')
                    app.DayaheadCheckBox.Enable = 'off';
                end
            end
                
        end

        % Callback function
        function SearchButtonPushed(app, event)
            filename = uigetfile;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            if strcmp(app.TypeDropDown.Value,'Energy Storage System')
                app.BatterySizePerVehicleKWEditFieldLabel.Enable = 'off';
                app.BatterySizePerVehicleKWEditField.Enable = 'off';
                app.ScenarioperMPEditFieldLabel.Text = 'Storage per MP (kW)';
                app.ScenarioperMPEditField.Value = 0.001;
                app.ScenarioperMPEditField_2.Value = 0.01;
                app.ScenarioperMPEditField_3.Value = 0.1;
                app.ScenarioperMPEditField_4.Value = 0.5;
                app.ScenarioperMPEditField_5.Value = 1;
                app.ScenarioperMPEditField_6.Value = 1.5;
                app.ScenarioperMPEditField_7.Value = 2;
                app.ScenarioperMPEditField_8.Value = 2.5;
                app.ScenarioperMPEditField_9.Value = 5;
                app.ScenarioperMPEditField_10.Value = 10;
                app.ScenarioperMPEditField_11.Value = 20;
                app.EnergytoPowerRatioMWhMWEditField.Value = 4;
            else
                app.BatterySizePerVehicleKWEditFieldLabel.Enable = 'on';
                app.BatterySizePerVehicleKWEditField.Enable = 'on';
                app.ScenarioperMPEditFieldLabel.Text = 'Number of EV per MP';
                app.ScenarioperMPEditField.Value = 0.0001;
                app.ScenarioperMPEditField_2.Value = 0.0005;
                app.ScenarioperMPEditField_3.Value = 0.001;
                app.ScenarioperMPEditField_4.Value = 0.005;
                app.ScenarioperMPEditField_5.Value = 0.01;
                app.ScenarioperMPEditField_6.Value = 0.02;
                app.ScenarioperMPEditField_7.Value = 0.05;
                app.ScenarioperMPEditField_8.Value = 0.1;
                app.ScenarioperMPEditField_9.Value = 0.2;
                app.ScenarioperMPEditField_10.Value = 0.5;
                app.ScenarioperMPEditField_11.Value = 1;
                app.EnergytoPowerRatioMWhMWEditField.Value = 7.5;
            end
        end

        % Button pushed function: CreateCaseButton
        function CreateCaseButtonPushed(app, event)
            Parameters = struct();
            Cost = struct();
            Option = struct();
            CaseName = app.CaseNameEditField.Value;
            filename = strcat(CaseName,'.mat');
            Year = app.YearSpinner.Value;
            Regime = app.RegimeDropDown.Value;
            Parameters.I =[];
            Parameters.J =[];
            if app.DayaheadCheckBox.Value
                Parameters.I = [Parameters.I,1];
            end
            if app.RealtimeCheckBox.Value || app.IntradayCheckBox.Value
                Parameters.I = [Parameters.I,2];
            end
            if app.BalancingCheckBox.Value
                Parameters.I = [Parameters.I,3];
            end
            if app.PrimaryControlCheckBox.Value || app.RegulationRegDCheckBox.Value
                Parameters.J = [Parameters.J,1];
            end
            if app.SecondaryControlCheckBox.Value || app.RegulationRegACheckBox.Value
                Parameters.J = [Parameters.J,2];
            end
            if app.SecondaryControlCheckBox_2.Value
                Parameters.J = [Parameters.J,3];
            end
            Parameters.E2P_ratio = app.EnergytoPowerRatioMWhMWEditField.Value;
            Parameters.eta_s = app.StorageEfficiencyEditField.Value;
            Parameters.eta_d = app.DischargeEfficiencyEditField.Value;
            Parameters.eta_c = app.ChargeEfficiencyEditField.Value;
            Parameters.BpEV = app.BatterySizePerVehicleKWEditField.Value;
            Parameters.delta_t = app.TimeStephEditField.Value;
            Parameters.s_0 = app.InitialSoCs_0EditField.Value;
            Parameters.zeta = (app.ReplacementEnergyCostKWhEditField.Value * app.EnergytoPowerRatioMWhMWEditField.Value + app.ReplacementPowerCostKWEditField.Value)/(app.OperationalLifeTimecyclesEditField.Value*app.EnergytoPowerRatioMWhMWEditField.Value*2);
            r = zeros(1,11);
            r(1)= app.ScenarioperMPEditField.Value;
            r(2)= app.ScenarioperMPEditField_2.Value;
            r(3)= app.ScenarioperMPEditField_3.Value;
            r(4)= app.ScenarioperMPEditField_4.Value;
            r(5)= app.ScenarioperMPEditField_5.Value;
            r(6)= app.ScenarioperMPEditField_6.Value;
            r(7)= app.ScenarioperMPEditField_7.Value;
            r(8)= app.ScenarioperMPEditField_8.Value;
            r(9)= app.ScenarioperMPEditField_9.Value;
            r(10)= app.ScenarioperMPEditField_10.Value;
            r(11)= app.ScenarioperMPEditField_11.Value;
            Parameters.r = r;
            Parameters.T = app.OptimizingWindowdEditField.Value*24/Parameters.delta_t;
            Cost.InvE = app.InvestmentEnergyCostKWhEditField.Value;
            Cost.InvP = app.InvestmentPowerCostKWEditField.Value;
            Cost.InvT = app.InvestmentTechnologyCostEditField.Value;
            Cost.RepE = app.ReplacementEnergyCostKWhEditField.Value;
            Cost.RepP = app.ReplacementPowerCostKWEditField.Value;
            Cost.life_nm = app.NominateLifeTimeyearEditField.Value;
            Cost.life_op = app.OperationalLifeTimecyclesEditField.Value;
            Cost.discount = app.DiscountRateEditField.Value;
            % Options
            if strcmp(app.TypeDropDown.Value,'Energy Storage System')
                Option.type = 'ESS';
            else
                Option.type = 'EV2G';
            end
            Option.Revenue = app.RevenueCheckBox.Value;
            Option.Cost = app.CostCheckBox.Value;
            Option.TechnologyConstraints = app.TechnologyConstraintsCheckBox.Value;
            Option.MarketConstraints = app.MarketConstraintsCheckBox.Value;
            Option.Risk = app.RiskCheckBox.Value;
            Option.mode = app.ModeSwitch.Value;
            Option.MarketSimulation = app.MarketSimulationCheckBox.Value;
            if strcmp(app.ModeSwitch.Value, 'Retrospective')
                cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/Cases
                save(filename, 'CaseName','Year', 'Regime', 'Parameters','Cost','Option');
            else
                cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/ProCases/temp
                load('MSOption.mat');
                cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/ProCases
                save(filename, 'CaseName','Year', 'Regime', 'Parameters','Cost','Option','MSOption');
            end
            app.RunCaseStudyButton.Enable = 'on';
        end

        % Value changed function: EnableAdvancedOptionsSwitch
        function EnableAdvancedOptionsSwitchValueChanged(app, event)
            value = app.EnableAdvancedOptionsSwitch.Value;
            if strcmp(value,'On')
                app.InitialSoCs_0EditField.Enable = 'on';
                app.TimeStephEditField.Enable = 'on';
                app.ScenarioperMPEditField.Enable = 'on';
                app.ScenarioperMPEditField_2.Enable = 'on';
                app.ScenarioperMPEditField_3.Enable = 'on';
                app.ScenarioperMPEditField_4.Enable = 'on';
                app.ScenarioperMPEditField_5.Enable = 'on';
                app.ScenarioperMPEditField_6.Enable = 'on';
                app.ScenarioperMPEditField_7.Enable = 'on';
                app.ScenarioperMPEditField_8.Enable = 'on';
                app.ScenarioperMPEditField_9.Enable = 'on';
                app.ScenarioperMPEditField_10.Enable = 'on';
                app.ScenarioperMPEditField_11.Enable = 'on';
                app.OptimizingWindowdEditField.Enable = 'on';
            else
                app.InitialSoCs_0EditField.Enable = 'off';
                app.TimeStephEditField.Enable = 'off';
                app.ScenarioperMPEditField.Enable = 'off';
                app.ScenarioperMPEditField_2.Enable = 'off';
                app.ScenarioperMPEditField_3.Enable = 'off';
                app.ScenarioperMPEditField_4.Enable = 'off';
                app.ScenarioperMPEditField_5.Enable = 'off';
                app.ScenarioperMPEditField_6.Enable = 'off';
                app.ScenarioperMPEditField_7.Enable = 'off';
                app.ScenarioperMPEditField_8.Enable = 'off';
                app.ScenarioperMPEditField_9.Enable = 'off';
                app.ScenarioperMPEditField_10.Enable = 'off';
                app.ScenarioperMPEditField_11.Enable = 'off';
                app.OptimizingWindowdEditField.Enable = 'off';
            end
        end

        % Button pushed function: RunCaseStudyButton
        function RunCaseStudyButtonPushed(app, event)
            CaseName = app.CaseNameEditField.Value;
            if strcmp(app.ModeSwitch.Value, 'Retrospective')
                cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab
                main
            end
            app.ShowResultButton.Enable = 'on';
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            if strcmp(value, 'Retrospective')
                app.RiskCheckBox.Enable = 'off';
                app.MarketSimulationCheckBox.Enable = 'off';
                app.OptionsButton.Enable = 'off';
                app.MarketSimulationCheckBox.Value = false;
            else
                app.RiskCheckBox.Enable = 'on';
                app.MarketSimulationCheckBox.Enable = 'on';
                app.OptionsButton.Enable = 'on';
                app.MarketSimulationCheckBox.Value = true;
            end
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            if strcmp(app.ModeSwitch.Value,'Retrospective')
                cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/DemoUI
                ResultUI
            end
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/DemoUI
            MarketSimulationOption
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralTab.Units = 'pixels';
            app.CaseNameEditFieldLabel = uilabel(app.GeneralTab);
            app.CaseNameEditFieldLabel.Text = 'Case Name';
            app.CaseNameEditField = uieditfield(app.GeneralTab, 'text');
            app.YearSpinnerLabel = uilabel(app.GeneralTab);
            app.YearSpinnerLabel.Enable = 'off';
            app.YearSpinnerLabel.Text = 'Year';
            app.YearSpinner = uispinner(app.GeneralTab);
            app.YearSpinner.Limits = [2007 2017];
            app.YearSpinner.Enable = 'off';
            app.YearSpinner.Value = 2016;
            app.RevenueCheckBox = uicheckbox(app.GeneralTab);
            app.RevenueCheckBox.Text = 'Revenue';
            app.RevenueCheckBox.Value = true;
            app.BasicInformationLabel = uilabel(app.GeneralTab);
            app.BasicInformationLabel.Text = 'Basic Information';
            app.ModuleSelectionLabel = uilabel(app.GeneralTab);
            app.ModuleSelectionLabel.Text = 'Module Selection';
            app.RiskCheckBox = uicheckbox(app.GeneralTab);
            app.RiskCheckBox.Enable = 'off';
            app.RiskCheckBox.Text = 'Risk';
            app.CostCheckBox = uicheckbox(app.GeneralTab);
            app.CostCheckBox.Text = 'Cost';
            app.CostCheckBox.Value = true;
            app.TechnologyConstraintsCheckBox = uicheckbox(app.GeneralTab);
            app.TechnologyConstraintsCheckBox.Text = 'Technology Constraints';
            app.TechnologyConstraintsCheckBox.Value = true;
            app.MarketConstraintsCheckBox = uicheckbox(app.GeneralTab);
            app.MarketConstraintsCheckBox.Text = 'Market Constraints';
            app.MarketConstraintsCheckBox.Value = true;
            app.OptionsButton = uibutton(app.GeneralTab, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Enable = 'off';
            app.OptionsButton.Text = 'Options...';
            app.MarketSimulationCheckBox = uicheckbox(app.GeneralTab);
            app.MarketSimulationCheckBox.Enable = 'off';
            app.MarketSimulationCheckBox.Text = 'Market Simulation';
            app.ModeSwitch = uiswitch(app.GeneralTab, 'slider');
            app.ModeSwitch.Items = {'Prospective', 'Retrospective'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Retrospective';
            app.MarketTab = uitab(app.TabGroup);
            app.MarketTab.Title = 'Market';
            app.MarketTab.Units = 'pixels';
            app.RegimeDropDownLabel = uilabel(app.MarketTab);
            app.RegimeDropDownLabel.Text = 'Regime';
            app.RegimeDropDown = uidropdown(app.MarketTab);
            app.RegimeDropDown.Items = {'PJM', 'Germany', 'AEMO-NSW'};
            app.RegimeDropDown.Value = 'AEMO-NSW';
            app.SelectButton = uibutton(app.MarketTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DayaheadCheckBox = uicheckbox(app.MarketTab);
            app.DayaheadCheckBox.Enable = 'off';
            app.DayaheadCheckBox.Text = 'Day-ahead';
            app.RealtimeCheckBox = uicheckbox(app.MarketTab);
            app.RealtimeCheckBox.Text = 'Real-time';
            app.EnergyMarketsLabel = uilabel(app.MarketTab);
            app.EnergyMarketsLabel.Text = 'Energy Markets';
            app.IntradayCheckBox = uicheckbox(app.MarketTab);
            app.IntradayCheckBox.Enable = 'off';
            app.IntradayCheckBox.Text = 'Intra-day';
            app.BalancingCheckBox = uicheckbox(app.MarketTab);
            app.BalancingCheckBox.Enable = 'off';
            app.BalancingCheckBox.Text = 'Balancing';
            app.ReserveMarketsLabel = uilabel(app.MarketTab);
            app.ReserveMarketsLabel.Text = 'Reserve Markets';
            app.PrimaryControlCheckBox = uicheckbox(app.MarketTab);
            app.PrimaryControlCheckBox.Enable = 'off';
            app.PrimaryControlCheckBox.Text = 'Primary Control';
            app.SecondaryControlCheckBox = uicheckbox(app.MarketTab);
            app.SecondaryControlCheckBox.Enable = 'off';
            app.SecondaryControlCheckBox.Text = 'Secondary Control (+)';
            app.SecondaryControlCheckBox_2 = uicheckbox(app.MarketTab);
            app.SecondaryControlCheckBox_2.Enable = 'off';
            app.SecondaryControlCheckBox_2.Text = 'Secondary Control (-)';
            app.RegulationRegDCheckBox = uicheckbox(app.MarketTab);
            app.RegulationRegDCheckBox.Enable = 'off';
            app.RegulationRegDCheckBox.Text = 'Regulation RegD';
            app.RegulationRegACheckBox = uicheckbox(app.MarketTab);
            app.RegulationRegACheckBox.Enable = 'off';
            app.RegulationRegACheckBox.Text = 'Regulation RegA';
            app.TechnologyTab = uitab(app.TabGroup);
            app.TechnologyTab.Title = 'Technology';
            app.TechnologyTab.Units = 'pixels';
            app.SelectButton_2 = uibutton(app.TechnologyTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.TypeDropDownLabel = uilabel(app.TechnologyTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.TechnologyTab);
            app.TypeDropDown.Items = {'Energy Storage System', 'Electric Vehicle to Grid'};
            app.TypeDropDown.Value = 'Energy Storage System';
            app.StorageEfficiencyEditFieldLabel = uilabel(app.TechnologyTab);
            app.StorageEfficiencyEditFieldLabel.Text = 'Storage Efficiency';
            app.StorageEfficiencyEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.StorageEfficiencyEditField.Limits = [0 1];
            app.StorageEfficiencyEditField.Value = 1;
            app.DischargeEfficiencyEditFieldLabel = uilabel(app.TechnologyTab);
            app.DischargeEfficiencyEditFieldLabel.Text = 'Discharge Efficiency';
            app.DischargeEfficiencyEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.DischargeEfficiencyEditField.Limits = [0 1];
            app.DischargeEfficiencyEditField.Value = 0.96;
            app.ChargeEfficiencyEditFieldLabel = uilabel(app.TechnologyTab);
            app.ChargeEfficiencyEditFieldLabel.Text = 'Charge Efficiency';
            app.ChargeEfficiencyEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.ChargeEfficiencyEditField.Limits = [0 1];
            app.ChargeEfficiencyEditField.Value = 0.96;
            app.BatterySizePerVehicleKWEditFieldLabel = uilabel(app.TechnologyTab);
            app.BatterySizePerVehicleKWEditFieldLabel.Enable = 'off';
            app.BatterySizePerVehicleKWEditFieldLabel.Text = 'Battery Size Per Vehicle (KW)';
            app.BatterySizePerVehicleKWEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.BatterySizePerVehicleKWEditField.Limits = [1 100];
            app.BatterySizePerVehicleKWEditField.Enable = 'off';
            app.BatterySizePerVehicleKWEditField.Value = 10;
            app.EnergytoPowerRatioMWhMWEditFieldLabel = uilabel(app.TechnologyTab);
            app.EnergytoPowerRatioMWhMWEditFieldLabel.Text = 'Energy to Power Ratio (MWh/MW)';
            app.EnergytoPowerRatioMWhMWEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.EnergytoPowerRatioMWhMWEditField.Limits = [0.001 1000];
            app.EnergytoPowerRatioMWhMWEditField.ValueDisplayFormat = '%11.3g';
            app.EnergytoPowerRatioMWhMWEditField.Value = 4;
            app.InvestmentEnergyCostKWhEditFieldLabel = uilabel(app.TechnologyTab);
            app.InvestmentEnergyCostKWhEditFieldLabel.Text = 'Investment Energy Cost ($/KWh)';
            app.InvestmentEnergyCostKWhEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.InvestmentEnergyCostKWhEditField.Limits = [0 1000];
            app.InvestmentEnergyCostKWhEditField.Value = 350;
            app.InvestmentPowerCostKWEditFieldLabel = uilabel(app.TechnologyTab);
            app.InvestmentPowerCostKWEditFieldLabel.Text = 'Investment Power Cost ($/KW)';
            app.InvestmentPowerCostKWEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.InvestmentPowerCostKWEditField.Limits = [0 1000];
            app.ReplacementEnergyCostKWhEditFieldLabel = uilabel(app.TechnologyTab);
            app.ReplacementEnergyCostKWhEditFieldLabel.Text = 'Replacement Energy Cost ($/KWh)';
            app.ReplacementEnergyCostKWhEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.ReplacementEnergyCostKWhEditField.Limits = [0 1000];
            app.ReplacementEnergyCostKWhEditField.Value = 150;
            app.ReplacementPowerCostKWEditFieldLabel = uilabel(app.TechnologyTab);
            app.ReplacementPowerCostKWEditFieldLabel.Text = 'Replacement Power Cost ($/KW)';
            app.ReplacementPowerCostKWEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.ReplacementPowerCostKWEditField.Limits = [0 1000];
            app.NominateLifeTimeyearEditFieldLabel = uilabel(app.TechnologyTab);
            app.NominateLifeTimeyearEditFieldLabel.Text = 'Nominate Life Time (year)';
            app.NominateLifeTimeyearEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.NominateLifeTimeyearEditField.Limits = [1 100];
            app.NominateLifeTimeyearEditField.Value = 10;
            app.OperationalLifeTimecyclesEditFieldLabel = uilabel(app.TechnologyTab);
            app.OperationalLifeTimecyclesEditFieldLabel.Text = 'Operational Life Time (cycles)';
            app.OperationalLifeTimecyclesEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.OperationalLifeTimecyclesEditField.Limits = [1 10000];
            app.OperationalLifeTimecyclesEditField.Value = 6000;
            app.DiscountRateLabel = uilabel(app.TechnologyTab);
            app.DiscountRateLabel.Text = 'Discount Rate (%)';
            app.DiscountRateEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.DiscountRateEditField.Limits = [1 100];
            app.DiscountRateEditField.Value = 10;
            app.InvestmentTechnologyCostEditFieldLabel = uilabel(app.TechnologyTab);
            app.InvestmentTechnologyCostEditFieldLabel.Text = 'Investment Technology Cost ($)';
            app.InvestmentTechnologyCostEditField = uieditfield(app.TechnologyTab, 'numeric');
            app.InvestmentTechnologyCostEditField.Limits = [0 1000];
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AdvancedTab.Units = 'pixels';
            app.InitialSoCs_0EditFieldLabel = uilabel(app.AdvancedTab);
            app.InitialSoCs_0EditFieldLabel.Text = 'Initial SoC (s_0)';
            app.InitialSoCs_0EditField = uieditfield(app.AdvancedTab, 'numeric');
            app.InitialSoCs_0EditField.Limits = [0 1];
            app.InitialSoCs_0EditField.Enable = 'off';
            app.TimeStephEditFieldLabel = uilabel(app.AdvancedTab);
            app.TimeStephEditFieldLabel.Text = 'Time Step (h)';
            app.TimeStephEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.TimeStephEditField.Limits = [0.5 1];
            app.TimeStephEditField.ValueDisplayFormat = '%11.2g';
            app.TimeStephEditField.Enable = 'off';
            app.TimeStephEditField.Value = 1;
            app.ScenarioperMPEditFieldLabel = uilabel(app.AdvancedTab);
            app.ScenarioperMPEditFieldLabel.Text = 'Scenario per MP';
            app.ScenarioperMPEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField.Limits = [0 100];
            app.ScenarioperMPEditField.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField.Enable = 'off';
            app.ScenarioperMPEditField.Value = 0.001;
            app.EnableAdvancedOptionsSwitchLabel = uilabel(app.AdvancedTab);
            app.EnableAdvancedOptionsSwitchLabel.Text = 'Enable Advanced Options';
            app.EnableAdvancedOptionsSwitch = uiswitch(app.AdvancedTab, 'slider');
            app.EnableAdvancedOptionsSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableAdvancedOptionsSwitchValueChanged, true);
            app.ScenarioperMPEditField_2 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_2.Limits = [0 100];
            app.ScenarioperMPEditField_2.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_2.Enable = 'off';
            app.ScenarioperMPEditField_2.Value = 0.01;
            app.ScenarioperMPEditField_3 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_3.Limits = [0 100];
            app.ScenarioperMPEditField_3.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_3.Enable = 'off';
            app.ScenarioperMPEditField_3.Value = 0.1;
            app.ScenarioperMPEditField_4 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_4.Limits = [0 100];
            app.ScenarioperMPEditField_4.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_4.Enable = 'off';
            app.ScenarioperMPEditField_4.Value = 0.5;
            app.ScenarioperMPEditField_5 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_5.Limits = [0 100];
            app.ScenarioperMPEditField_5.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_5.Enable = 'off';
            app.ScenarioperMPEditField_5.Value = 1;
            app.ScenarioperMPEditField_6 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_6.Limits = [0 100];
            app.ScenarioperMPEditField_6.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_6.Enable = 'off';
            app.ScenarioperMPEditField_6.Value = 1.5;
            app.ScenarioperMPEditField_7 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_7.Limits = [0 100];
            app.ScenarioperMPEditField_7.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_7.Enable = 'off';
            app.ScenarioperMPEditField_7.Value = 2;
            app.ScenarioperMPEditField_8 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_8.Limits = [0 100];
            app.ScenarioperMPEditField_8.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_8.Enable = 'off';
            app.ScenarioperMPEditField_8.Value = 2.5;
            app.ScenarioperMPEditField_9 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_9.Limits = [0 100];
            app.ScenarioperMPEditField_9.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_9.Enable = 'off';
            app.ScenarioperMPEditField_9.Value = 5;
            app.ScenarioperMPEditField_10 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_10.Limits = [0 100];
            app.ScenarioperMPEditField_10.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_10.Enable = 'off';
            app.ScenarioperMPEditField_10.Value = 10;
            app.ScenarioperMPEditField_11 = uieditfield(app.AdvancedTab, 'numeric');
            app.ScenarioperMPEditField_11.Limits = [0 100];
            app.ScenarioperMPEditField_11.ValueDisplayFormat = '%11.2g';
            app.ScenarioperMPEditField_11.Enable = 'off';
            app.ScenarioperMPEditField_11.Value = 20;
            app.OptimizingWindowdEditFieldLabel = uilabel(app.AdvancedTab);
            app.OptimizingWindowdEditFieldLabel.Text = 'Optimizing Window (d)';
            app.OptimizingWindowdEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.OptimizingWindowdEditField.Limits = [1 14];
            app.OptimizingWindowdEditField.ValueDisplayFormat = '%11.2g';
            app.OptimizingWindowdEditField.Enable = 'off';
            app.OptimizingWindowdEditField.Value = 7;
            app.CreateCaseButton = uibutton(app.UIFigure, 'push');
            app.CreateCaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCaseButtonPushed, true);
            app.CreateCaseButton.Text = 'Create Case';
            app.RunCaseStudyButton = uibutton(app.UIFigure, 'push');
            app.RunCaseStudyButton.ButtonPushedFcn = createCallbackFcn(app, @RunCaseStudyButtonPushed, true);
            app.RunCaseStudyButton.Enable = 'off';
            app.RunCaseStudyButton.Text = 'Run Case Study';
            app.ShowResultButton = uibutton(app.UIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FlexibilityUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end