classdef MarketSimulationOption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WindKnobLabel                 matlab.ui.control.Label
        WindKnob                      matlab.ui.control.DiscreteKnob
        GenerationLabel               matlab.ui.control.Label
        OptionsforsimulatingfutureelectricitymarketLabel  matlab.ui.control.Label
        SolarKnobLabel                matlab.ui.control.Label
        SolarKnob                     matlab.ui.control.DiscreteKnob
        InflexibleegignitenuclearKnobLabel  matlab.ui.control.Label
        InflexibleKnob                matlab.ui.control.DiscreteKnob
        FlexiblegasturbinepumphydroKnobLabel  matlab.ui.control.Label
        FlexibleKnob                  matlab.ui.control.DiscreteKnob
        ConsumptionKnobLabel          matlab.ui.control.Label
        ConsumptionKnob               matlab.ui.control.DiscreteKnob
        FlexibleconsumptionKnobLabel  matlab.ui.control.Label
        FlexibleconsumptionKnob       matlab.ui.control.DiscreteKnob
        ConsumptionLabel              matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            MSOption = struct();
            MSOption.wind = str2double(app.WindKnob);
            MSOption.solar = str2double(app.SolarKnob);
            MSOption.inflex = str2double(app.InflexibleKnob);
            MSOption.solar = str2double(app.FlexibleKnob);
            MSOption.consumption = str2double(app.ConsumptionKnob);
            MSOption.consumption_flex = str2double(app.FlexibleconsumptionKnob)/100;
            cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/ProCases/temp
            save('MSOption.mat','MSOption');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.WindKnobLabel = uilabel(app.UIFigure);
            app.WindKnobLabel.Text = 'Wind';
            app.WindKnob = uiknob(app.UIFigure, 'discrete');
            app.WindKnob.Items = {'0.5', '1', '1.2', '1.5', '2.0', '2.5', '5', '10'};
            app.WindKnob.Value = '1';
            app.GenerationLabel = uilabel(app.UIFigure);
            app.GenerationLabel.Text = 'Generation';
            app.OptionsforsimulatingfutureelectricitymarketLabel = uilabel(app.UIFigure);
            app.OptionsforsimulatingfutureelectricitymarketLabel.Text = 'Options for simulating future electricity market';
            app.SolarKnobLabel = uilabel(app.UIFigure);
            app.SolarKnobLabel.Text = 'Solar';
            app.SolarKnob = uiknob(app.UIFigure, 'discrete');
            app.SolarKnob.Items = {'0.5', '1', '1.2', '1.5', '2.0', '2.5', '5', '10'};
            app.SolarKnob.Value = '1';
            app.InflexibleegignitenuclearKnobLabel = uilabel(app.UIFigure);
            app.InflexibleegignitenuclearKnobLabel.Text = 'Inflexible (e.g. ignite, nuclear)';
            app.InflexibleKnob = uiknob(app.UIFigure, 'discrete');
            app.InflexibleKnob.Items = {'0.1', '0.5', '0.8', '1', '1.2', '1.5', '2', '5'};
            app.InflexibleKnob.Value = '1';
            app.FlexiblegasturbinepumphydroKnobLabel = uilabel(app.UIFigure);
            app.FlexiblegasturbinepumphydroKnobLabel.Text = 'Flexible (gas-turbine, pump hydro)';
            app.FlexibleKnob = uiknob(app.UIFigure, 'discrete');
            app.FlexibleKnob.Items = {'0.8', '1', '1.2', '1.5', '2.0', '2.5', '5', '10'};
            app.FlexibleKnob.Value = '1';
            app.ConsumptionKnobLabel = uilabel(app.UIFigure);
            app.ConsumptionKnobLabel.Text = 'Consumption';
            app.ConsumptionKnob = uiknob(app.UIFigure, 'discrete');
            app.ConsumptionKnob.Items = {'0.9', '0.95', '1', '1.05', '1.1', '1.2', '1.5', '2'};
            app.ConsumptionKnob.Value = '1';
            app.FlexibleconsumptionKnobLabel = uilabel(app.UIFigure);
            app.FlexibleconsumptionKnobLabel.Text = 'Flexible consumption (%)';
            app.FlexibleconsumptionKnob = uiknob(app.UIFigure, 'discrete');
            app.FlexibleconsumptionKnob.Items = {'0', '5', '10', '20', '30', '40', '50'};
            app.FlexibleconsumptionKnob.Value = '0';
            app.ConsumptionLabel = uilabel(app.UIFigure);
            app.ConsumptionLabel.Text = 'Consumption';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MarketSimulationOption()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end