classdef ResultUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UpdatethelistButton          matlab.ui.control.Button
        CaseSelectionDropDownLabel   matlab.ui.control.Label
        CaseSelectionDropDown        matlab.ui.control.DropDown
        SelectButton                 matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        SummaryTab                   matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        RegimeEditFieldLabel         matlab.ui.control.Label
        RegimeEditField              matlab.ui.control.EditField
        TechnologyEditFieldLabel     matlab.ui.control.Label
        TechnologyEditField          matlab.ui.control.EditField
        MarketPlacesEditFieldLabel   matlab.ui.control.Label
        MarketPlacesEditField        matlab.ui.control.EditField
        CalculateResultButton        matlab.ui.control.Button
        MeteringPointEditFieldLabel  matlab.ui.control.Label
        MeteringPointEditField       matlab.ui.control.EditField
        Lamp                         matlab.ui.control.Lamp
        CalculatingUsuallytakesca30sLabel  matlab.ui.control.Label
        SystemPerformanceTab         matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        ShowButton_3                 matlab.ui.control.Button
        AddButton_3                  matlab.ui.control.Button
        ListofitemsListBoxLabel      matlab.ui.control.Label
        ListofitemsListBox           matlab.ui.control.ListBox
        xaxisEditFieldLabel          matlab.ui.control.Label
        xaxisEditField               matlab.ui.control.NumericEditField
        yaxisEditFieldLabel          matlab.ui.control.Label
        yaxisEditField               matlab.ui.control.NumericEditField
        xaxisEditField_2             matlab.ui.control.NumericEditField
        xaxisEditField_3             matlab.ui.control.NumericEditField
        RescaleButton                matlab.ui.control.Button
        LegendButton                 matlab.ui.control.Button
        SystemBehaviorTab            matlab.ui.container.Tab
        ShowButton_2                 matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        MonthSpinnerLabel            matlab.ui.control.Label
        MonthSpinner                 matlab.ui.control.Spinner
        DaySpinnerLabel              matlab.ui.control.Label
        DaySpinner                   matlab.ui.control.Spinner
        MonthSpinner_2Label          matlab.ui.control.Label
        MonthSpinner_2               matlab.ui.control.Spinner
        DaySpinner_2Label            matlab.ui.control.Label
        DaySpinner_2                 matlab.ui.control.Spinner
        SystemSizeDropDown           matlab.ui.control.DropDown
        EditField                    matlab.ui.control.NumericEditField
        SystemSizeLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        Results = struct(); % Description
        legend_list = cell(0); % Description
    end

    methods (Access = private)
    
        function results = CalResult(app)
            cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/
            CaseName = app.CaseSelectionDropDown.Value;
            Result
            results.MP = MP;
            results.Result_rev_cost = Result_rev_cost;
            results.Result_summary = Result_summary;
            results.Scenarios = Scenarios;
            results.I = Parameters.I;
            results.J = Parameters.J;
            results.PI_e_I = DATA.PI_e_I;
            results.PI_e_J = DATA.PI_e_J;
            results.PI_r_J = DATA.PI_r_J;
            results.time_vector = time_vector;
            results.delta_t = Parameters.delta_t;
            results.Year = Year;
        end
    end


    methods (Access = private)

        % Button pushed function: UpdatethelistButton
        function UpdatethelistButtonPushed(app, event)
            %app.CaseNameDropDown = uidropdown(app.UIFigure);
            cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/Cases
            list = dir;
            CaseNames = cell(0);
            for i = 1:size(list,1)
                if list(i).isdir && ~strcmp(list(i).name,'.') && ~strcmp(list(i).name,'..')
                    CaseNames{end+1} = list(i).name;
                end
            end
            app.CaseSelectionDropDown.Items = CaseNames;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            CaseName = app.CaseSelectionDropDown.Value;
            cd /Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/Cases
            CaseFileName = strcat(CaseName,'.mat');
            load(CaseFileName);
            app.RegimeEditField.Value = Regime;
            if strcmp(Option.type, 'ESS')
                app.TechnologyEditField.Value = 'Energy Storage System';
                app.UITable.ColumnName{end} = 'System Size (MW)';
                app.SystemSizeLabel.Text = '# System Size (MW):';
                xlabel(app.UIAxes, 'System size (MW)');
            else
                app.TechnologyEditField.Value = 'Electric Vehicle to Grid';
                app.UITable.ColumnName{end} = 'Number of EV (k)';
                app.SystemSizeLabel.Text = '# Number of EV (k):';
                xlabel(app.UIAxes, 'Number of EV (k)');
            end
            Marketplace = '';
            for i = 1:length(Parameters.I)
                switch Parameters.I(i)
                    case 1
                        Marketplace = strcat(Marketplace,'@', 'Day-ahead');
                    case 2
                        if strcmp(Regime,'Germany')
                            Marketplace = strcat(Marketplace,'@', 'Intra-day');
                        else
                            Marketplace = strcat(Marketplace,'@', 'Real-time');
                        end
                    case 3
                        Marketplace = strcat(Marketplace,'@', 'Balancing');
                end 
            end
            for j = 1:length(Parameters.J)
                switch Parameters.J(j)
                    case 1
                        if strcmp(Regime,'PJM')
                            Marketplace = strcat(Marketplace,'@', 'RegD');
                        else
                            Marketplace = strcat(Marketplace,'@', 'PCR');
                        end
                    case 2
                        if strcmp(Regime,'PJM')
                            Marketplace = strcat(Marketplace,'@', 'RegA');
                        else
                            Marketplace = strcat(Marketplace,'@', 'SCR(+)');
                        end
                    case 3
                        Marketplace = strcat(Marketplace,'@', 'SCR(-)');
                end 
            end
            app.MarketPlacesEditField.Value = Marketplace;
            app.CalculateResultButton.Enable = 'on';
            app.Lamp.Color = [0.61 0.61 0.61];
            app.ListofitemsListBox.Enable = 'off';
            if strcmp(Option.type, 'ESS')
                app.ListofitemsListBox.Items = {'Revenue', 'Operating profit', 'Profit', 'Fixed cost', 'Operating cost'};
            else
                app.ListofitemsListBox.Items = {'Revenue', 'Operating profit', 'Profit', 'Fixed cost', 'Operating cost', 'EV driving cost'};
            end
        end

        % Button pushed function: CalculateResultButton
        function CalculateResultButtonPushed(app, event)
            app.Lamp.Color = [1 0 0];
            app.CalculatingUsuallytakesca30sLabel.FontColor = [1 0 0];
            app.Results = CalResult(app);
            app.Lamp.Color = [0 1 0];
            app.CalculatingUsuallytakesca30sLabel.FontColor = [0.9373 0.9373 0.9373];
            app.MeteringPointEditField.Value = num2str(app.Results.MP);
            app.UITable.Data = [app.Results.Scenarios' num2cell(app.Results.Result_summary)];
            app.ListofitemsListBox.Enable = 'on';
            List = cell(1,size(app.Results.Result_rev_cost,1));
            for i = 1:size(app.Results.Result_rev_cost,1)
                List{i} = num2str(i);
            end
            app.SystemSizeDropDown.Items = List;
        end

        % Button pushed function: ShowButton_3
        function ShowButton_3Pushed(app, event)
            hold(app.UIAxes, 'off')
            Result_rev_cost = app.Results.Result_rev_cost;
            SystemSize = Result_rev_cost(:,1)/1000;
            Revenue = (Result_rev_cost(:,3)-Result_rev_cost(:,6))/1000000;
            OperatingProfit = (Result_rev_cost(:,3) -Result_rev_cost(:,4)-Result_rev_cost(:,6))/1000000;
            Profit = (Result_rev_cost(:,3) -Result_rev_cost(:,4)-Result_rev_cost(:,5)-Result_rev_cost(:,6))/1000000;
            FixedCost = Result_rev_cost(:,5)/1000000;
            OperatingCost = Result_rev_cost(:,4)/1000000;
            EVDrivingCost = -Result_rev_cost(:,6)/1000000;
            if strcmp(app.ListofitemsListBox.Value, 'Revenue')
                plot(app.UIAxes,SystemSize,Revenue)
                app.legend_list = {'Revenue'};
            elseif strcmp(app.ListofitemsListBox.Value, 'Operating profit')
                plot(app.UIAxes,SystemSize,OperatingProfit)
                app.legend_list = {'Operating profit'};
            elseif strcmp(app.ListofitemsListBox.Value, 'Profit')
                plot(app.UIAxes,SystemSize,Profit)
                app.legend_list = {'Profit'};
            elseif strcmp(app.ListofitemsListBox.Value, 'Operating cost')
                plot(app.UIAxes,SystemSize,OperatingCost)
                app.legend_list = {'Operating cost'};
            elseif strcmp(app.ListofitemsListBox.Value, 'Fixed cost')
                plot(app.UIAxes,SystemSize,FixedCost)
                app.legend_list = {'Fixed cost'};
            elseif strcmp(app.ListofitemsListBox.Value, 'EV driving cost')
                plot(app.UIAxes,SystemSize,EVDrivingCost)
                app.legend_list = {'EV driving cost'};    
            end
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            hold(app.UIAxes,'on')
            Result_rev_cost = app.Results.Result_rev_cost;
            SystemSize = Result_rev_cost(:,1)/1000;
            Revenue = (Result_rev_cost(:,3)-Result_rev_cost(:,6))/1000000;
            OperatingProfit = (Result_rev_cost(:,3) -Result_rev_cost(:,4)-Result_rev_cost(:,6))/1000000;
            Profit = (Result_rev_cost(:,3) -Result_rev_cost(:,4)-Result_rev_cost(:,5)-Result_rev_cost(:,6))/1000000;
            FixedCost = Result_rev_cost(:,5)/1000000;
            OperatingCost = Result_rev_cost(:,4)/1000000;
            EVDrivingCost = -Result_rev_cost(:,6)/1000000;
            if strcmp(app.ListofitemsListBox.Value, 'Revenue')
                plot(app.UIAxes,SystemSize,Revenue)
                app.legend_list{end+1} = 'Revenue';
            elseif strcmp(app.ListofitemsListBox.Value, 'Operating profit')
                plot(app.UIAxes,SystemSize,OperatingProfit)
                app.legend_list{end+1} = 'Operating profit';
            elseif strcmp(app.ListofitemsListBox.Value, 'Profit')
                plot(app.UIAxes,SystemSize,Profit)
                app.legend_list{end+1} = 'Profit';
            elseif strcmp(app.ListofitemsListBox.Value, 'Operating cost')
                plot(app.UIAxes,SystemSize,OperatingCost)
                app.legend_list{end+1} = 'Operating cost';
            elseif strcmp(app.ListofitemsListBox.Value, 'Fixed cost')
                plot(app.UIAxes,SystemSize,FixedCost)
                app.legend_list{end+1} = 'Fixed cost';
            elseif strcmp(app.ListofitemsListBox.Value, 'EV driving cost')
                plot(app.UIAxes,SystemSize,EVDrivingCost)
                app.legend_list{end+1} = 'EV driving cost';
            end
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            x_low = app.xaxisEditField.Value;
            x_up = app.xaxisEditField_2.Value;
            y_low = app.yaxisEditField.Value;
            y_up = app.xaxisEditField_3.Value;
            xlim(app.UIAxes,[x_low x_up])
            ylim(app.UIAxes,[y_low y_up])
        end

        % Button pushed function: LegendButton
        function LegendButtonPushed(app, event)
            legend(app.UIAxes,app.legend_list,'Location','southeast');
        end

        % Button pushed function: ShowButton_2
        function ShowButton_2Pushed(app, event)
            i = str2num(app.SystemSizeDropDown.Value);
            app.EditField.Value = round(app.Results.Result_rev_cost(i,1)/1000);
            CaseName = app.CaseSelectionDropDown.Value;
            CaseFolderName = strcat('/Users/fxleos/Documents/MasterThesis/FXLeibility/FXLeibility/FXLeibility.Matlab/Cases/',CaseName);
            cd(CaseFolderName)
            filename = strcat(app.SystemSizeDropDown.Value,'.mat');
            load(filename);
            time_start  = datetime(app.Results.Year,app.MonthSpinner.Value,app.DaySpinner.Value,00,30,00);
            time_end  = datetime(app.Results.Year,app.MonthSpinner_2.Value,app.DaySpinner_2.Value,23,59,59);
            time_index = app.Results.time_vector >= time_start & app.Results.time_vector <= time_end;
            if ~isempty(app.Results.I)
                plot(app.UIAxes_2,app.Results.time_vector(time_index),app.Results.PI_e_I(time_index,app.Results.I));
                hold(app.UIAxes_2,'on');
            end
            if ~isempty(app.Results.J)
                plot(app.UIAxes_2,app.Results.time_vector(time_index),app.Results.PI_e_J(time_index,app.Results.J));
                hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,app.Results.time_vector(time_index),app.Results.PI_r_J(time_index,app.Results.J));
            end
            hold(app.UIAxes_2,'off');
            list_legend_1= cell(0);
            Regime = app.RegimeEditField.Value;
            for i = 1:length(app.Results.I)
                switch app.Results.I(i)
                    case 1
                        list_legend_1{end+1} = 'Day-ahead';
                    case 2
                        if strcmp(Regime,'Germany')
                            list_legend_1{end+1} ='Intra-day';
                        else
                            list_legend_1{end+1} ='Real-time';
                        end
                    case 3
                        list_legend_1{end+1} = 'Balancing';
                end 
            end
            for j = 1:length(app.Results.J)
                switch app.Results.J(j)
                    case 1
                        if strcmp(Regime,'PJM')
                            list_legend_1{end+1} ='RegD Energy';
                        else
                            list_legend_1{end+1} ='PCR Energy';
                        end
                    case 2
                        if strcmp(Regime,'PJM')
                            list_legend_1{end+1} ='RegA Energy';
                        else
                            list_legend_1{end+1} ='SCR(+) Energy';
                        end
                    case 3
                        list_legend_1{end+1} ='SCR(-) Energy';
                end
            end
            for j = 1:length(app.Results.J)
                switch app.Results.J(j)
                    case 1
                        if strcmp(Regime,'PJM')
                            list_legend_1{end+1} ='RegD Reserve';
                        else
                            list_legend_1{end+1} ='PCR Reserve';
                        end
                    case 2
                        if strcmp(Regime,'PJM')
                            list_legend_1{end+1} ='RegA Reserve';
                        else
                            list_legend_1{end+1} ='SCR(+) Reserve';
                        end
                    case 3
                        list_legend_1{end+1} ='SCR(-) Reserve';
                end
            end
            legend(app.UIAxes_2,list_legend_1,'Location','northeast');
            if ~isempty(app.Results.I)
                plot(app.UIAxes_3,app.Results.time_vector(time_index),E_D(time_index,:));
                hold(app.UIAxes_3,'on');
                plot(app.UIAxes_3,app.Results.time_vector(time_index),E_C(time_index,:));
            end
            if ~isempty(app.Results.J)
                plot(app.UIAxes_3,app.Results.time_vector(time_index),R(time_index,:));
            end
            hold(app.UIAxes_3,'off');
            list_legend = cell(0);
            for i = 1:length(app.Results.I)
                switch app.Results.I(i)
                    case 1
                        list_legend{end+1} = 'Day-ahead Discharging';
                    case 2
                        if strcmp(Regime,'Germany')
                            list_legend{end+1} ='Intra-day Discharging';
                        else
                            list_legend{end+1} ='Real-time Discharging';
                        end
                    case 3
                        list_legend{end+1} = 'Balancing Discharging';
                end 
            end
            for i = 1:length(app.Results.I)
                switch app.Results.I(i)
                    case 1
                        list_legend{end+1} = 'Day-ahead Charging';
                    case 2
                        if strcmp(Regime,'Germany')
                            list_legend{end+1} ='Intra-day Charging';
                        else
                            list_legend{end+1} ='Real-time Charging';
                        end
                    case 3
                        list_legend{end+1} = 'Balancing Discharging';
                end
            end
            for j = 1:length(app.Results.J)
                switch app.Results.J(j)
                    case 1
                        if strcmp(Regime,'PJM')
                            list_legend{end+1} ='RegD';
                        else
                            list_legend{end+1} ='PCR';
                        end
                    case 2
                        if strcmp(Regime,'PJM')
                            list_legend{end+1} ='RegA';
                        else
                            list_legend{end+1} ='SCR(+)';
                        end
                    case 3
                        list_legend{end+1} ='SCR(-)';
                end
            end
            legend(app.UIAxes_3,list_legend,'Location','northeast');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UpdatethelistButton = uibutton(app.UIFigure, 'push');
            app.UpdatethelistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatethelistButtonPushed, true);
            app.UpdatethelistButton.Text = 'Update the list';
            app.CaseSelectionDropDownLabel = uilabel(app.UIFigure);
            app.CaseSelectionDropDownLabel.Text = 'Case Selection';
            app.CaseSelectionDropDown = uidropdown(app.UIFigure);
            app.CaseSelectionDropDown.Items = {};
            app.CaseSelectionDropDown.Value = {};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SummaryTab.Units = 'pixels';
            app.UITable = uitable(app.SummaryTab);
            app.UITable.ColumnName = {'  Scenario  '; 'Revenue ($m)'; 'Operating Profit ($m)'; 'Profit ($m)'; 'System Size'};
            app.UITable.RowName = {};
            app.RegimeEditFieldLabel = uilabel(app.SummaryTab);
            app.RegimeEditFieldLabel.Text = 'Regime';
            app.RegimeEditField = uieditfield(app.SummaryTab, 'text');
            app.RegimeEditField.Editable = 'off';
            app.TechnologyEditFieldLabel = uilabel(app.SummaryTab);
            app.TechnologyEditFieldLabel.Text = 'Technology';
            app.TechnologyEditField = uieditfield(app.SummaryTab, 'text');
            app.TechnologyEditField.Editable = 'off';
            app.MarketPlacesEditFieldLabel = uilabel(app.SummaryTab);
            app.MarketPlacesEditFieldLabel.Text = 'Market Places';
            app.MarketPlacesEditField = uieditfield(app.SummaryTab, 'text');
            app.MarketPlacesEditField.Editable = 'off';
            app.CalculateResultButton = uibutton(app.SummaryTab, 'push');
            app.CalculateResultButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateResultButtonPushed, true);
            app.CalculateResultButton.Enable = 'off';
            app.CalculateResultButton.Text = 'Calculate Result';
            app.MeteringPointEditFieldLabel = uilabel(app.SummaryTab);
            app.MeteringPointEditFieldLabel.Text = 'Metering Point';
            app.MeteringPointEditField = uieditfield(app.SummaryTab, 'text');
            app.MeteringPointEditField.Editable = 'off';
            app.Lamp = uilamp(app.SummaryTab);
            app.CalculatingUsuallytakesca30sLabel = uilabel(app.SummaryTab);
            app.CalculatingUsuallytakesca30sLabel.Enable = 'off';
            app.CalculatingUsuallytakesca30sLabel.Text = '(Calculating. Usually takes ca. 30s ...)';
            app.SystemPerformanceTab = uitab(app.TabGroup);
            app.SystemPerformanceTab.Title = 'System Performance';
            app.SystemPerformanceTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.SystemPerformanceTab);
            title(app.UIAxes, 'System performace with different level of sizes');
            xlabel(app.UIAxes, 'System size (MW)');
            ylabel(app.UIAxes, 'Revenue / Profit / Cost ($m)');
            app.ShowButton_3 = uibutton(app.SystemPerformanceTab, 'push');
            app.ShowButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_3Pushed, true);
            app.ShowButton_3.Text = 'Show ->';
            app.AddButton_3 = uibutton(app.SystemPerformanceTab, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add ->';
            app.ListofitemsListBoxLabel = uilabel(app.SystemPerformanceTab);
            app.ListofitemsListBoxLabel.Enable = 'off';
            app.ListofitemsListBoxLabel.Text = 'List of items';
            app.ListofitemsListBox = uilistbox(app.SystemPerformanceTab);
            app.ListofitemsListBox.Items = {'Revenue', 'Operating profit', 'Profit', 'Fixed cost', 'Operating cost'};
            app.ListofitemsListBox.Enable = 'off';
            app.ListofitemsListBox.Value = 'Revenue';
            app.xaxisEditFieldLabel = uilabel(app.SystemPerformanceTab);
            app.xaxisEditFieldLabel.Text = 'x-axis';
            app.xaxisEditField = uieditfield(app.SystemPerformanceTab, 'numeric');
            app.yaxisEditFieldLabel = uilabel(app.SystemPerformanceTab);
            app.yaxisEditFieldLabel.Text = 'y-axis';
            app.yaxisEditField = uieditfield(app.SystemPerformanceTab, 'numeric');
            app.xaxisEditField_2 = uieditfield(app.SystemPerformanceTab, 'numeric');
            app.xaxisEditField_3 = uieditfield(app.SystemPerformanceTab, 'numeric');
            app.RescaleButton = uibutton(app.SystemPerformanceTab, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Re-scale->';
            app.LegendButton = uibutton(app.SystemPerformanceTab, 'push');
            app.LegendButton.ButtonPushedFcn = createCallbackFcn(app, @LegendButtonPushed, true);
            app.LegendButton.Text = 'Legend ->';
            app.SystemBehaviorTab = uitab(app.TabGroup);
            app.SystemBehaviorTab.Title = 'System Behavior';
            app.SystemBehaviorTab.Units = 'pixels';
            app.ShowButton_2 = uibutton(app.SystemBehaviorTab, 'push');
            app.ShowButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_2Pushed, true);
            app.ShowButton_2.Text = 'Show';
            app.UIAxes_2 = uiaxes(app.SystemBehaviorTab);
            title(app.UIAxes_2, 'Price signals');
            ylabel(app.UIAxes_2, 'Price ($/MWh)');
            app.UIAxes_3 = uiaxes(app.SystemBehaviorTab);
            title(app.UIAxes_3, 'System Behavior');
            ylabel(app.UIAxes_3, 'Energy / Reserve (MWh / MW)');
            app.MonthSpinnerLabel = uilabel(app.SystemBehaviorTab);
            app.MonthSpinnerLabel.Text = 'Month';
            app.MonthSpinner = uispinner(app.SystemBehaviorTab);
            app.MonthSpinner.Limits = [1 12];
            app.MonthSpinner.Value = 1;
            app.DaySpinnerLabel = uilabel(app.SystemBehaviorTab);
            app.DaySpinnerLabel.Text = 'Day';
            app.DaySpinner = uispinner(app.SystemBehaviorTab);
            app.DaySpinner.Limits = [1 31];
            app.DaySpinner.Value = 1;
            app.MonthSpinner_2Label = uilabel(app.SystemBehaviorTab);
            app.MonthSpinner_2Label.Text = 'Month';
            app.MonthSpinner_2 = uispinner(app.SystemBehaviorTab);
            app.MonthSpinner_2.Limits = [1 12];
            app.MonthSpinner_2.Value = 1;
            app.DaySpinner_2Label = uilabel(app.SystemBehaviorTab);
            app.DaySpinner_2Label.Text = 'Day';
            app.DaySpinner_2 = uispinner(app.SystemBehaviorTab);
            app.DaySpinner_2.Limits = [1 31];
            app.DaySpinner_2.Value = 1;
            app.SystemSizeDropDown = uidropdown(app.SystemBehaviorTab);
            app.SystemSizeDropDown.Items = {'1'};
            app.SystemSizeDropDown.Value = '1';
            app.EditField = uieditfield(app.SystemBehaviorTab, 'numeric');
            app.SystemSizeLabel = uilabel(app.SystemBehaviorTab);
            app.SystemSizeLabel.Text = '# System Size:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ResultUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end